classdef simulation_ui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DefendersSpinnerLabel   matlab.ui.control.Label
        DefendersSpinner        matlab.ui.control.Spinner
        SpinnerLabel            matlab.ui.control.Label
        Spinner                 matlab.ui.control.Spinner
        ResetButton             matlab.ui.control.Button
        StartsimulationButton   matlab.ui.control.Button
        ReplaysimulationButton  matlab.ui.control.Button
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartsimulationButton
        function start_simulation(app, event)
            
            map = binaryOccupancyMap(20,20);
            x = 9:12;
            y = 9:12;
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(map, xy, ones(16,1))
            show(map);
            x2 = [18 15 16 3 4 2];
            y2 = [3.5 3.8 4.5 10 11 11.5];
            i = 0;
            while i < 20
               clf;
               x2 = x2 + 0.8;
               y2 = y2 + 0.08;
               hold on;
               voronoi(x2,y2);
               show(map);
               pause(0.2);
               i = i + 1;
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DefendersSpinnerLabel = uilabel(app.LeftPanel);
            app.DefendersSpinnerLabel.Text = 'Defenders';
            app.DefendersSpinner = uispinner(app.LeftPanel);
            app.DefendersSpinner.Limits = [0 Inf];
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = '';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 Inf];
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.StartsimulationButton = uibutton(app.LeftPanel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @start_simulation, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.LeftPanel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end