classdef simulation_ui2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        SetsimulationTab              matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        AgentsEditFieldLabel          matlab.ui.control.Label
        AgentsEditField               matlab.ui.control.NumericEditField
        AttackersEditFieldLabel       matlab.ui.control.Label
        AttackersEditField            matlab.ui.control.NumericEditField
        StartsimulationButton         matlab.ui.control.Button
        ReplaysimulationButton        matlab.ui.control.Button
        DisplayrolesSwitchLabel       matlab.ui.control.Label
        DisplayrolesSwitch            matlab.ui.control.Switch
        ParametersTab                 matlab.ui.container.Tab
        Panel_2                       matlab.ui.container.Panel
        SimulationruntimeEditFieldLabel  matlab.ui.control.Label
        SimulationruntimeEditField    matlab.ui.control.NumericEditField
        TimestepEditFieldLabel        matlab.ui.control.Label
        TimestepEditField             matlab.ui.control.NumericEditField
        AgentslinearspeedEditFieldLabel  matlab.ui.control.Label
        AgentslinearspeedEditField    matlab.ui.control.NumericEditField
        ProtectedzonelengthEditFieldLabel  matlab.ui.control.Label
        ProtectedzonelengthEditField  matlab.ui.control.NumericEditField
        FieldlengthEditFieldLabel     matlab.ui.control.Label
        FieldlengthEditField          matlab.ui.control.NumericEditField
        ApplyButton                   matlab.ui.control.Button
        SightframesizeEditFieldLabel  matlab.ui.control.Label
        SightframesizeEditField       matlab.ui.control.NumericEditField
        GraphsTab                     matlab.ui.container.Tab
        Panel_3                       matlab.ui.container.Panel
        DataforgraphsPanel            matlab.ui.container.Panel
        MaxagentsEditFieldLabel       matlab.ui.control.Label
        MaxagentsEditField            matlab.ui.control.NumericEditField
        MaxspeedEditFieldLabel        matlab.ui.control.Label
        MaxspeedEditField             matlab.ui.control.NumericEditField
        MinfieldsizeLabel             matlab.ui.control.Label
        MinfieldsizeEditField         matlab.ui.control.NumericEditField
        SetnumberofiterationsSpinnerLabel  matlab.ui.control.Label
        SetnumberofiterationsSpinner  matlab.ui.control.Spinner
        YaxisListBoxLabel             matlab.ui.control.Label
        YaxisListBox                  matlab.ui.control.ListBox
        XaxisListBoxLabel             matlab.ui.control.Label
        XaxisListBox                  matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        changeParam = false; % determine if need to change parameters
        Env % multi-robot environment
        NumOfRob; % total # of robots
        NumOfAgents; % number of agents
        NumOfAtc;% number of attackers
        OcMap; % Occupancy map
        FieldLen = 100;% length of field side
        PZoneLen = 20;% Protected Zone side length
        pZoneMaxX; % Protected Zone max x position
        pZoneMinX; % Protected Zone min x position
        pZoneMaxY; % Protected Zone max y position
        pZoneMinY; % Protected Zone min y position
        Pzone_poly; % pzone polyshape
        sbZoneMinX; % standby min x position
        sbZoneMinY; % standby min y position
        sbZoneMaxX; % standby max x position
        sbZoneMaxY; % standby max y position
        sbDist = 0.25; % standby distance ratio
        Poses; % Position matrix for the robots
        FrameSize = 10;
        FrameInitAg = 0.2; % Frame ratio for initial agent positions
        LifeFlags; % Life markers for the robots
        SimTime = 60; % total simulation time (sec)
        SampleTime = 0.1; % time stemp (sec)
        TimeVec; % time vector
        ATC_EVADE_RAD = 4; % attackers evade radius
        ATC_TER_RAD = 2; % attackers terminated radius
        roles; % roles vector
        agentV = 1.5; % agents velocity
        ScatterSize = 20;
        DisplayVec; % vector of scatter objects for display
        
        
    end
    
    methods (Access = private)
        
         % check if the protected zone is covered
         function [covered,uncover_center]=is_pz_covered(app,poly_vec)
            % create one polyshape
            u_cell = union(poly_vec);
            uncover_center = [Inf Inf];
            % intersect the pzone and the cells
            inter = intersect(u_cell,app.Pzone_poly);
            covered = round(area(inter),3) == area(app.Pzone_poly);
            if(~covered)
                [cx,cy] = centroid(subtract(app.Pzone_poly,u_cell));
                uncover_center = [cx cy];
                if(isnan(cx)) % Check if there is no polygon
                    u_cell
                    app.Pzone_poly
                end
            end
            
        end
        
%         % check if the protected zone is covered
%         function covered=is_pz_covered(app,vertices,cells)
%             covered = false;
%             % set pzone coordinates
%             pzone_x = linespace(app.pZoneMinX,app.pZoneMaxX,app.PZoneLen)'; 
%             pzone_x = [pzone_x;pzone_x];
%             pzone_y = linespace(app.pZoneMinY,app.pZoneMaxY,app.PZoneLen)';
%             pzone_y = [pzone_y;pzone_y];
%             
%             % set fist cell coordinates
%             curr_cell = vertices(cells(1));
%             % check if pzone is covered by the first cell
%             [in,on] = inpolygon(pzone_x,pzone_y,curr_cell(:,1),curr_cell(:,2));
%             % set current cover status
%             curr_cover = in|on;
%             
%             % run on all the others cells
%             for i=2:length(cells)
%                 % set current cell coordinates
%                 curr_cell = vertices(cells(i));
%                 cx = curr_cell(:,1);
%                 cy = curr_cell(:,2);        
%                 % check if pzone is covered by current cell
%                 [in,on] = inpolygon(pzone_x,pzone_y,cx,cy);
%                 % set current cover status
%                 curr_cover = curr_cover|(in|on);
%                 % check if all pzone is fully covered
%                 if ~any(curr_cover(:) == 0)
%                     covered = true;
%                     break;
%                 end
%             end
%         end


        % return position for attacker
        function res = get_atacker_init_pos(app)
            pos1 = (rand(1,1)-0.5)*2; % get rand in [-1,1]
            negIdx = (pos1<0);
            posIdx = (pos1>=0);
            % map to [-10,0] or [100,110]
            pos1 = app.FrameSize .* pos1;
            pos1 = ((app.FieldLen/2)+ pos1).*posIdx ...
                + (-(app.FieldLen/2)+ pos1).*negIdx  ...
                + app.FieldLen/2;
            % map to [-10,110]
            pos2 = (-app.FrameSize+(app.FieldLen+(2*app.FrameSize)) .* rand(1,1));
            
            coinFlip = (rand(1,1)-0.5)*2;
            if coinFlip >= 0
                res = [pos1; pos2;pi*rand(1,1)];
            else
                res = [pos2; pos1;pi*rand(1,1)];
            end
        end


        % return position for defender
        function pos = get_agent_init_pos(app)
            offset = app.FrameInitAg * app.FieldLen;
            frameSize = offset*2+app.PZoneLen;
            pos = [(rand(2, 1))*frameSize+offset;pi*rand(1,1)];
        end
        
        % get relevent point of protected zone
        function target = get_point_of_pzone(app,point)
            curr_x = point(1);
            curr_y = point(2);
            if app.pZoneMinX <= curr_x && curr_x <= app.pZoneMaxX
                x = curr_x;
            elseif curr_x > app.pZoneMaxX
                x = app.pZoneMaxX;
            else
                x = app.pZoneMinX;
            end 
            
            if app.pZoneMinY <= curr_y && curr_y <= app.pZoneMaxY
                y = curr_y;
            elseif curr_x > app.pZoneMaxY
                y = app.pZoneMaxY;
            else
                y = app.pZoneMinY;
            end
            target = [x y];
        end
        
%         function idx = get_closest_point(app,points)
%             min_dis = Inf;
%             idx = 0;
%             for i=1:length(points)
%                 % get relevent point
%                 pZone = app.get_point_of_pzone(points(i,:));
%                 % calc distance
%                 curr_dis = norm(pZone - points(i));
%                 % update current
%                 if curr_dis < min_dis
%                     idx = i;
%                     min_dis = curr_dis;
%                 end
%             end
%         end
        
function [w,evade] = calc_atk_angular_speed(app,evade_radius,pos,robots,idx)
            w = 0;
            evade = false;
            temp_w = 0;
            heading = mod(pos(3),2*pi);
            [~,n] = size(robots);
            % run on all robots
            for i=1:n
                u = robots(:,i) - pos(1:2);
                v = [0;1];
                % calc distance
                distance = norm(u);
                if  distance <= evade_radius
                    % calc angle between the vectors
                    cos_theta = max(min(dot(u,v)/distance,1),-1);
                    theta = real(acosd(cos_theta));
                    r_pos = robots(:,i);
                    if r_pos(2) < pos(2)
                        theta = theta + pi;   
                    elseif r_pos(2) == pos(2)
                        if r_pos(1) < pos(1)
                            theta = theta + pi;
                        end
                    end
                    theta = mod(theta,2*pi);
                    diff = heading - theta;
                    if diff >= -pi/3 && diff <=  0
                       temp_w = interp1([0,pi/3],[0.5,2],pi/3+diff);
                       if  abs(temp_w) > abs(w)
                           w = temp_w;
                           evade = true;
                       end
                    elseif diff <= pi/3 && diff > 0
                       w = interp1([0,pi/3],[-2,-0.5],pi/3-diff);
                       evade = true;
                       break;
                    end
                end
            end
        end
        
        % attackers controler
        function vel = AtCtrl(app, rIdx)
            v = 0;
            w = 0;
            pos = app.Poses(:,rIdx);
            if(app.LifeFlags(rIdx))
                v = 1;
                defenders_pos = app.Poses(1:2,1:app.NumOfAgents);
                norms = vecnorm(defenders_pos - pos(1:2));
                is_below_rad = norms <= app.ATC_TER_RAD;
                
                % check if terminated
                if find(is_below_rad) 
                    % set the attacker as terminated
                    app.LifeFlags(rIdx) = 0;
                    v = 0;
                    w = 0;
                    % remove attacker from field
                    scatter(pos(1),pos(2),200,'X','black','LineWidth',2);
                end
                if(app.LifeFlags(rIdx))
                    [w,evade] = app.calc_atk_angular_speed(app.ATC_EVADE_RAD,pos,defenders_pos,rIdx);
                    if ~evade
                        % move toward protected zone
                        % get point to move to
                        target = app.get_point_of_pzone(pos);
                        w = utils.calc_angular_speed_by_point(pos,target); 
                    end
                end
            end
            vel = bodyToWorld([v;0;w],pos);
        end
        
        % Determine role
        function role=determine_role(app,cell)
            inter = intersect(cell,app.Pzone_poly);
            role = int8(area(inter) ~= 0);%TODO: add error tolerance +-5%
        end
        
        % calc vel for active keeper and active chaser
        function [v,w]=get_vel_active_policy(app,rIdx,cells,closest_att)
            v = 0;
            w = 0;
            pose = app.Poses(:,rIdx); 
            % calc angular speed
            % find edge middle
            inter = intersect(cells{rIdx}',cells{closest_att}','rows');
            ver1 = inter(1,:)';
            ver2 = inter(2,:)';
            target = (ver1 + ver2)./2;
            w = utils.calc_angular_speed_by_point(pose,target);
            v = app.agentV;
        end
        
        % Agents keepers controler   
        function vel = AgKeepCtrl(app, rIdx,is_cover,uncover_center,neighbors,cells,reduce_poses)
            v = 0;
            w = 0;
            pose = reduce_poses(:,rIdx);          
            % Find closest attacker, which the agent is closest to.
            cl_to_neigh = find(neighbors(rIdx,:));
            ag_pos = pose(1:2);
            min_dist = Inf;
            closest_att=-1;
            if(numel(cl_to_neigh))
                [~,cl_to_att] = find(neighbors(cl_to_neigh,:));
                cl_to_att = flip(cl_to_att);
                is_neigh = cl_to_att == rIdx;
                neigh_idx = find(is_neigh);
                if(numel(neigh_idx) > 0)
                    att_pos = reduce_poses(1:2, cl_to_neigh(neigh_idx));
                    dist = vecnorm(att_pos-ag_pos);
                    [~,idx] = min(dist);
                    closest_att = cl_to_neigh(neigh_idx(idx));
                end
            end
            min_dist = Inf;
            for i=1:numel(cl_to_neigh)
                cl_to_att = find(neighbors(cl_to_neigh(i),:));
                is_neigh = cl_to_att == rIdx;
                neigh_idx = find(is_neigh);
                if(numel(neigh_idx) > 0)
                    att_pos = reduce_poses(1:2, cl_to_neigh(i));
                    dist = norm(att_pos-ag_pos);
                    if dist < min_dist
                        min_dist =dist;
                        closest_att = cl_to_neigh(i);
                    end
                end
            end
            if closest_att == -1
                % ******* Passive defence policy *********
                % check if p zone is covered
                if(~is_cover)
                    % move to mass center
                    w = utils.calc_angular_speed_by_point(pose,uncover_center);
                    if(isnan(w)) %TODO: delete later
                        rIdx
                        pose 
                        v
                        w
                    end
                    v= app.agentV;
                end
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                    app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'c',"filled");
                end
            else % TODO: Remove later
                % ******* Active defence policy ********
                [v,w] = app.get_vel_active_policy(rIdx,cells,closest_att);
                if(isnan(w))%TODO: delete later
                    rIdx
                    pose 
                    v
                    w
                end
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                     app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'m',"filled");
                end
            end
            vel = bodyToWorld([v;0;w],pose);
        end
        
        % Agents chasers controler
        function vel = AgChaseCtrl(app, rIdx,neighbors,cells,reduce_poses)
            v = 0;
            w = 0;
            pose = reduce_poses(:,rIdx);
            % Find closest attacker
            cl_to_neigh = find(neighbors(rIdx,:));
            ag_pos = pose(1:2);
            min_dist = Inf;
            closest_att=-1;
            if(numel(cl_to_neigh))
                att_pos = reduce_poses(1:2, cl_to_neigh(:));
                dist = vecnorm(att_pos-ag_pos);
                [min_dist,idx] = min(dist);
                closest_att = cl_to_neigh(idx);
            end
            % ******* Active-Chase policy ********
            if(closest_att ~= -1)
                [v,w] = app.get_vel_active_policy(rIdx,cells,closest_att);
                % check if display roles is on
                if(app.DisplayrolesSwitch.Value ~= "Off")
                    hold on
                     app.DisplayVec(rIdx) = scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'yellow',"filled");
                end
            else
                % ******* Reserve policy ********
                % get all keepers neighbors
                k = find(app.roles(:));
                keeprs_neighbors = neighbors(k,:);
                closest_att = -1;
                % find min distance to neighbor
                if(k)
                    [~,attackers] = find(keeprs_neighbors(:,:));
                    dist = vecnorm(ag_pos - reduce_poses(1:2,attackers));
                    [~,idx] = min(dist);
                    closest_att = attackers(idx);
                end
%                 for i=1:length(k)
%                     attackers = find(keeprs_neighbors(i,:));
%                     for j=1:length(attackers)
%                         curr_att = attackers(j);
%                         curr_dist = norm(ag_pos - reduce_poses(1:2,curr_att));
%                         if(curr_dist < min_dist)
%                             min_dist = curr_dist;
%                             closest_att = curr_att;
%                         end
%                     end   
%                 end
                if(closest_att ~= -1)
                    w = utils.calc_angular_speed_by_point(pose,reduce_poses(1:2,closest_att));
                    v = app.agentV;
                    % check if display roles is on
                    if(app.DisplayrolesSwitch.Value ~= "Off")
                        hold on
                        app.DisplayVec(rIdx) =  scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'green',"filled");
                    end
                else 
                    % ******* Stand-by policy ********
                    target = ag_pos;
                    % inside standby zone
                    if((ag_pos(1) <= app.sbZoneMaxX && ag_pos(1) >= app.sbZoneMinX) && ...
                            (ag_pos(2) <= app.sbZoneMaxY && ag_pos(2) >= app.sbZoneMinY))
                        v = 0;
                        w = 0;
                    else 
                        % check to which point on the standby zone to move to
                        if((ag_pos(1) < app.sbZoneMinX))
                            target(1) = app.sbZoneMinX;
                        elseif((ag_pos(1) > app.sbZoneMaxX))
                            target(1) = app.sbZoneMaxX;
                        end
                        
                        if((ag_pos(2) < app.sbZoneMinY))
                            target(2) = app.sbZoneMinY;
                        elseif((ag_pos(2) > app.sbZoneMaxY))
                            target(2) = app.sbZoneMaxY;
                        end    
                        
                        v = app.agentV;
                        w = utils.calc_angular_speed_by_point(pose,target);
                    end
                    % check if display roles is on
                    if(app.DisplayrolesSwitch.Value ~= "Off")
                        hold on
                        app.DisplayVec(rIdx) =  scatter(ag_pos(1),ag_pos(2),app.ScatterSize,'black',"filled");
                    end
                end
            end
            vel = bodyToWorld([v;0;w],pose);
        end
  
        function poly_vec=create_polyVec(app,cells,numAgs)
            poly_vec = polyshape.empty(numAgs,0);
            % create polyshape
            for i=1:numAgs
                 % get current cell coordinates
                curr_cell = cells{i};
                cx = curr_cell(1,:);
                cy = curr_cell(2,:);   
                % add cell to polyshape vector
                curr_poly = polyshape(cx,cy);
                poly_vec(i) = curr_poly;
                app.roles(i) = app.determine_role(curr_poly);
            end
        end
        
        % robots controler
        function vel = robCtrl(app,vel)
%             % crreate lookup table for the robots
%             look_up = zeros(app.NumOfRob);
%             curr_idx = 1;
%             for i=1:app.NumOfRob
%                 if(app.LifeFlags(i))
%                   look_up(i) = curr_idx;
%                   curr_idx = curr_idx + 1;
%                 end
%             end
            % poses of living robots
            reduce_poses = app.Poses(:,find(app.LifeFlags));
            % get voronoi cells
            lBound = [-app.FrameSize -app.FrameSize];
            hBound = [app.FieldLen+app.FrameSize app.FieldLen+app.FrameSize];
            cells = utils.get_voronoi(reduce_poses(1:2,:),lBound,hBound);
            
            % create polyshapes for each voronoi cell and assign role for each agent
            %poly_vec = app.create_polyVec(cells,app.NumOfAgents);
            poly_vec = polyshape.empty(app.NumOfAgents,0);
            poly_vec = arrayfun(@(cell) polyshape(cell{1}(1,:),cell{1}(2,:)),cells(1:app.NumOfAgents))';
            app.roles = arrayfun(@(poly)app.determine_role(poly),poly_vec);            
            
            % get neighbors of each robot
            neighbors = utils.get_neighbors(app.NumOfAgents,reduce_poses(1:2,:),cells);  
            
            % check if pzone is coverd by friendly voronoi cells
            [is_cover,uncover_center] = app.is_pz_covered(poly_vec);
            
            % run controllers
            indexes = 1:app.NumOfRob;
            % Keeper controller vel(:,indexes(app.roles == 1))
            vel(:,indexes(app.roles == 1)) = cell2mat(arrayfun(@(idx)app.AgKeepCtrl(idx,is_cover,uncover_center,neighbors,cells,reduce_poses),indexes(app.roles == 1),'UniformOutput',false));
            % Chaser controller
            vel(:,indexes(app.roles == 0)) = cell2mat(arrayfun(@(idx)app.AgChaseCtrl(idx,neighbors,cells,reduce_poses),indexes(app.roles == 0),'UniformOutput',false));
            % attacker controller
            vel(:,app.NumOfAgents + 1:end) = cell2mat(arrayfun(@(idx)app.AtCtrl(idx),indexes(app.NumOfAgents + 1:end),'UniformOutput',false));
            
            % run controllers
%             for i=1:app.NumOfRob
%                 if(app.LifeFlags(i))
%                     if i <= app.NumOfAgents
%                         if app.roles(i)
%                            % Keeper controller
%                            vel(:,i) = app.AgKeepCtrl(i,is_cover,uncover_center,neighbors,cells,reduce_poses);
%                         else
%                            % Chaser controller
%                            vel(:,i) = app.AgChaseCtrl(i,neighbors,cells,reduce_poses);
%                         end
%                     else
%                        vel(:,i) = app.AtCtrl(i);
%                     end
%                 end
%             end
        end
        
        % check if there is an attacker in the protected zone
        function is_breached=check_if_defense_breached(app)
            is_breached = false;
            % run on all attackers
            for i=app.NumOfAgents + 1:app.NumOfRob
                % add 1 for the robot radius
                pos = app.Poses(1:2,i);
                % closest on the pzone to the attacker
                pZone_point = pos;
                % find point on pzone
                if((pos(1) < app.pZoneMinX))
                    pZone_point(1) = app.pZoneMinX;
                elseif((pos(1) > app.pZoneMaxX))
                    pZone_point(1) = app.pZoneMaxX;
                end
                
                if((pos(2) < app.pZoneMinY))
                    pZone_point(2) = app.pZoneMinY;
                elseif((pos(2) > app.pZoneMaxY))
                    pZone_point(2) = app.pZoneMaxY;
                end    
                
                % calc the distance
                dist = norm(pos - pZone_point);
                if(dist - app.Env.robotRadius(1) <= 0)
                    is_breached = true;
                    break;
                end
            end
        end
        
        function change_displayVec(app,curr)
            if(~isa(curr,'matlab.graphics.GraphicsPlaceholder'))
                set(curr,'Visible','off')
            end
        end
        
        % run the simulation loop
        function [win,defense_time]=simulation_loop(app)
            win = -1;
            vel=zeros(3, app.NumOfRob);
            % run on each timestamp
           % vor = voronoi(app.Poses(1,:)',app.Poses(2,:)');
            % set empty roles
            app.roles = zeros(1,app.NumOfAgents);
            % set scatter display vector
            app.DisplayVec = gobjects(1,app.NumOfAgents);
            for currStemp =2:numel(app.TimeVec)
                % update env
                app.Env(1:app.NumOfRob, app.Poses);
                xlim([0 app.FieldLen]);   % Without this, axis resizing can slow things down
                ylim([0 app.FieldLen]); 
                
                % display voronoi
                % set display off only if vor exist
                if(exist('vor',"var"))
                    set(vor,'Visible','off')
                end
                
                arrayfun(@(x) app.change_displayVec(x),app.DisplayVec);
                
                % poses of living robots
                reduce_poses = app.Poses(1:2,find(app.LifeFlags));
                hold on
                vor = voronoi(reduce_poses(1,:)',reduce_poses(2,:)');
                set(vor,'Visible','on')
                % run robot controller
                vel = app.robCtrl(vel);

                % check if there is an attacker in the protected zone
                if (app.check_if_defense_breached())
                    win = 0;
                     % display win message
                     icon = imresize(imread('robot_devil_lost.jpg'),[100 100]);
                     %waitfor(msgbox('Agents failed to protected the zone','Failure','custom',icon));
                     defense_time = app.TimeVec(currStemp);
                    break;
                % cheack if all the attackers are dead
                elseif(length(find(app.LifeFlags)) == app.NumOfAgents)
                    win = 1;
                    % display win message
                    icon = imresize(imread('bender_success.png'),[100 100]);
                    %waitfor(msgbox('Agents successfully protected the zone','Success','custom',icon));
                    defense_time = app.TimeVec(currStemp);
                    break;
                end
                
                % Calculate next timestamp poses
                app.Poses = app.Poses + vel*app.SampleTime;
            end
            if(win == -1)
                win = 1;
                defense_time = app.SimTime;
            end
        end
        
        function set_parameters(app,field_length,agent_speed)
            % set data from gui
            if(isnan(agent_speed))
                app.agentV = app.AgentslinearspeedEditField.Value;
            else
                app.agentV = agent_speed;
            end
            if(isnan(field_length))
                app.FieldLen = app.FieldlengthEditField.Value;
            else
                app.FieldLen = field_length;
            end
            
            app.PZoneLen = app.ProtectedzonelengthEditField.Value;
            app.SampleTime = app.TimestepEditField.Value;
            app.SimTime = app.SimulationruntimeEditField.Value;
            app.FrameSize = app.SightframesizeEditField.Value;
            
            % create occupancy map
            app.OcMap = binaryOccupancyMap(app.FieldLen, app.FieldLen);
            assignin("base","map", app.OcMap)
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            x = fieldMid-pzMid+1:fieldMid+pzMid;
            y = fieldMid-pzMid+1:fieldMid+pzMid;
            [p,q] = meshgrid(x,y);
            xy = [p(:) q(:)];
            setOccupancy(app.OcMap, xy, ones((app.PZoneLen)^2,1))
            
            % set protected zone
            fieldMid = fix(app.FieldLen/2);
            pzMid = fix (app.PZoneLen/2);
            app.pZoneMaxX = fieldMid+pzMid;
            app.pZoneMinX = fieldMid-pzMid;
            app.pZoneMaxY = fieldMid+pzMid;
            app.pZoneMinY = fieldMid-pzMid;
            pzone_x = [app.pZoneMinX;app.pZoneMinX;app.pZoneMaxX;app.pZoneMaxX];
            pzone_y = [app.pZoneMinY;app.pZoneMaxY;app.pZoneMaxY;app.pZoneMinY];
            app.Pzone_poly = polyshape(pzone_x,pzone_y);
            
            % set stand-by zone
            app.sbZoneMinX = app.pZoneMinX*(1-app.sbDist);
            app.sbZoneMaxX = app.FieldLen*app.sbDist + app.pZoneMaxX*(1-app.sbDist);
            app.sbZoneMinY = app.pZoneMinY*(1-app.sbDist);
            app.sbZoneMaxY = app.FieldLen*app.sbDist + app.pZoneMaxY*(1-app.sbDist); 
            
            app.changeParam = false;
        end
        
        function [win_vector,defense_time,agents_vector,field_vector,speed_vector]=prepare_data_for_exp(app)
            numIter = app.SetnumberofiterationsSpinner.Value;
            defense_time = zeros(1,numIter);
            win_vector = zeros(1,numIter);
            agents_vector = nan;
            field_vector = nan;
            speed_vector = nan;
            % check which vectors to create
            for i=1:numel(app.XaxisListBox.Value)
                switch app.XaxisListBox.Value{i}
                    case 'Number of agents'
                        diff = app.MaxagentsEditField.Value - app.NumOfAgents;
                        agents_vector = app.NumOfAgents:diff/(numIter-1):app.MaxagentsEditField.Value;
                    case 'Field size'
                        diff =  app.FieldLen - app.MinfieldsizeEditField.Value;
                        field_vector = app.MinfieldsizeEditField.Value:diff/(numIter -1):app.FieldLen;
                    case 'Linear speed'
                        diff =  app.MaxspeedEditField.Value - app.agentV;
                        speed_vector = app.agentV:diff/(numIter-1):app.MaxspeedEditField.Value;
                end
             end            
        end
        
        function prepare_data_for_simulation(app)
            % init multi-robot environment
            app.Env = MultiRobotEnv(app.NumOfRob);
            app.Env.robotRadius = 1 ;
            app.Env.showTrajectory = false;
            app.Env.mapName = "map";
            
            % divide robots to groups
            a = repmat([0 0 1],[app.NumOfAgents 1]);
            b = repmat([1 0 0],[app.NumOfAtc 1]);
            app.Env.robotColors = [a;b];
            % Initialize poses
            app.Poses= [];
            for j=1:app.NumOfRob
                if j<= app.NumOfAgents
                    pos = app.get_agent_init_pos();
                    app.Poses=[app.Poses pos];
                else
                    pos = app.get_atacker_init_pos();
                    app.Poses=[app.Poses pos];
                end
            end
        
            % set time vector
            app.TimeVec = 0:app.SampleTime:app.SimTime;
            % initialize life markers
            app.LifeFlags = ones(app.NumOfRob,1);    
        end        
        
        % run an experiment
        function run_experiments(app,win_vector,defense_time,agents_vector,field_vector,speed_vector)
            numIter = numel(win_vector);
            % check if need to plot graphs
            if(isnan(speed_vector(1)) && isnan(agents_vector(1)) && isnan(field_vector(1)))
                app.prepare_data_for_simulation();
                % run the simulation
                [win_vector(:,1),defense_time(:,1)] = app.simulation_loop();
            else
                for j=1:numel(app.XaxisListBox.Value)
                    switch app.XaxisListBox.Value{j}
                        case 'Number of agents'
                            for i=1:numIter
                                % update number of robots and agents
                                app.set_parameters(nan,nan);
                                app.NumOfRob = app.NumOfAgents + app.NumOfAtc;
                                app.agentV = app.AgentslinearspeedEditField.Value;
                                app.prepare_data_for_simulation();
                                % run the simulation
                                [win_vector(:,i),defense_time(:,i)] = app.simulation_loop();
                            end
                            figure(2)
                            plot3(agents_vector,defense_time,win_vector);
                            
                        case 'Field size'
                             for i=1:numIter
                                % update number of robots and agents
                                app.NumOfAgents = app.AgentsEditField.Value;
                                app.NumOfRob = app.NumOfAgents + app.NumOfAtc;
                                app.set_parameters(field_vector(i),nan);
                                app.prepare_data_for_simulation();
                                % run the simulation
                                [win_vector(:,i),defense_time(:,i)] = app.simulation_loop();
                             end
                        case 'Linear speed'
                             for i=1:numIter
                                % update number of robots and agents
                                app.NumOfAgents = app.AgentsEditField.Value;
                                app.NumOfRob = app.NumOfAgents + app.NumOfAtc;
                                app.set_parameters(nan,speed_vector(i));
                                app.prepare_data_for_simulation();
                                % run the simulation
                                [win_vector(:,i),defense_time(:,i)] = app.simulation_loop();
                             end
                             figure(2)
                             plot3(speed_vector,defense_time,win_vector);
                    end
                end            
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set default parameters 
            app.set_parameters(nan,nan);
        end

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            % check if the user choose the parameters
            if(app.changeParam)
                app.set_parameters(nan,nan);
            end
            
            % get User values
            numOfAgents = app.AgentsEditField.Value;
            numOfAttackers = app.AttackersEditField.Value;
            numIter = app.SetnumberofiterationsSpinner.Value;
            
            app.NumOfRob = numOfAttackers+numOfAgents;
            app.NumOfAgents = numOfAgents;
            app.NumOfAtc = numOfAttackers;
            
            % prepare data for experiments
            [win_vector,defense_time,agents_vector,field_vector,speed_vector]=app.prepare_data_for_exp();
            app.run_experiments(win_vector,defense_time,agents_vector,field_vector,speed_vector);
            
%             % run for selected iterations
%             for i=1:numIter
%                 % init multi-robot environment
%                 app.Env = MultiRobotEnv(app.NumOfRob);
%                 app.Env.robotRadius = 1 ;
%                 app.Env.showTrajectory = false;
%                 app.Env.mapName = "map";
%                 
%                 % divide robots to groups
%                 a = repmat([0 0 1],[numOfAgents 1]);
%                 b = repmat([1 0 0],[numOfAttackers 1]);
%                 app.Env.robotColors = [a;b];
%                 % Initialize poses
%                 app.Poses= [];
%                 for j=1:app.NumOfRob
%                     if j<= numOfAgents
%                         pos = app.get_agent_init_pos();
%                         app.Poses=[app.Poses pos];
%                     else
%                         pos = app.get_atacker_init_pos();
%                         app.Poses=[app.Poses pos];
%                     end
%                 end
%             
%                 % set time vector
%                 app.TimeVec = 0:app.SampleTime:app.SimTime;
%                 % initialize life markers
%                 app.LifeFlags = ones(app.NumOfRob,1);    
%                 %try
%                     is_win = app.simulation_loop();
%                     win_vector(:,i) = is_win;
%                 %catch
%                     % delete multi-robot environment
%                     %delete(app.Env);
%                 %end
            %end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % close all figures
            close all
            % clear all variables
            evalin('base','clear all')
            % delete app
            delete(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            app.changeParam = true;
        end

        % Value changed function: YaxisListBox
        function YaxisListBoxValueChanged(app, event)
            value = app.YaxisListBox.Value;
        end

        % Value changed function: XaxisListBox
        function XaxisListBoxValueChanged(app, event)
            value = app.XaxisListBox.Value;
            agents = false;
            field = false;
            speed = false;
            for i=1:numel(value)
                switch value{i}
                    case 'Number of agents'
                        agents = true;
                    case 'Field size'
                        field = true;
                    case 'Linear speed'
                        speed = true;
                end
            end
            app.MaxagentsEditField.Enable = agents;
            app.MaxagentsEditFieldLabel.Enable = agents;
            app.MinfieldsizeEditField.Enable = field;
            app.MinfieldsizeLabel.Enable = field;
            app.MaxspeedEditField.Enable = speed;
            app.MaxspeedEditFieldLabel.Enable = speed;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetsimulationTab = uitab(app.TabGroup);
            app.SetsimulationTab.Title = 'Set simulation';
            app.Panel = uipanel(app.SetsimulationTab);
            app.AgentsEditFieldLabel = uilabel(app.Panel);
            app.AgentsEditFieldLabel.Text = 'Agents';
            app.AgentsEditField = uieditfield(app.Panel, 'numeric');
            app.AgentsEditField.Limits = [1 Inf];
            app.AgentsEditField.RoundFractionalValues = 'on';
            app.AgentsEditField.Value = 2;
            app.AttackersEditFieldLabel = uilabel(app.Panel);
            app.AttackersEditFieldLabel.Text = 'Attackers';
            app.AttackersEditField = uieditfield(app.Panel, 'numeric');
            app.AttackersEditField.Limits = [0 Inf];
            app.AttackersEditField.RoundFractionalValues = 'on';
            app.AttackersEditField.Value = 1;
            app.StartsimulationButton = uibutton(app.Panel, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Text = 'Start simulation';
            app.ReplaysimulationButton = uibutton(app.Panel, 'push');
            app.ReplaysimulationButton.Text = 'Replay simulation';
            app.DisplayrolesSwitchLabel = uilabel(app.Panel);
            app.DisplayrolesSwitchLabel.Text = 'Display roles';
            app.DisplayrolesSwitch = uiswitch(app.Panel, 'slider');
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.Panel_2 = uipanel(app.ParametersTab);
            app.SimulationruntimeEditFieldLabel = uilabel(app.Panel_2);
            app.SimulationruntimeEditFieldLabel.Text = 'Simulation run time';
            app.SimulationruntimeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SimulationruntimeEditField.Value = 60;
            app.TimestepEditFieldLabel = uilabel(app.Panel_2);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.Panel_2, 'numeric');
            app.TimestepEditField.Value = 0.1;
            app.AgentslinearspeedEditFieldLabel = uilabel(app.Panel_2);
            app.AgentslinearspeedEditFieldLabel.Text = 'Agents linear speed';
            app.AgentslinearspeedEditField = uieditfield(app.Panel_2, 'numeric');
            app.AgentslinearspeedEditField.Value = 1;
            app.ProtectedzonelengthEditFieldLabel = uilabel(app.Panel_2);
            app.ProtectedzonelengthEditFieldLabel.Text = 'Protected zone length';
            app.ProtectedzonelengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.ProtectedzonelengthEditField.Value = 20;
            app.FieldlengthEditFieldLabel = uilabel(app.Panel_2);
            app.FieldlengthEditFieldLabel.Text = 'Field length';
            app.FieldlengthEditField = uieditfield(app.Panel_2, 'numeric');
            app.FieldlengthEditField.Value = 100;
            app.ApplyButton = uibutton(app.Panel_2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SightframesizeEditFieldLabel = uilabel(app.Panel_2);
            app.SightframesizeEditFieldLabel.Text = 'Sight frame size';
            app.SightframesizeEditField = uieditfield(app.Panel_2, 'numeric');
            app.SightframesizeEditField.Value = 10;
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.Panel_3 = uipanel(app.GraphsTab);
            app.DataforgraphsPanel = uipanel(app.Panel_3);
            app.DataforgraphsPanel.Title = 'Data for graphs';
            app.MaxagentsEditFieldLabel = uilabel(app.DataforgraphsPanel);
            app.MaxagentsEditFieldLabel.Enable = 'off';
            app.MaxagentsEditFieldLabel.Text = 'Max agents';
            app.MaxagentsEditField = uieditfield(app.DataforgraphsPanel, 'numeric');
            app.MaxagentsEditField.Limits = [1 Inf];
            app.MaxagentsEditField.RoundFractionalValues = 'on';
            app.MaxagentsEditField.Enable = 'off';
            app.MaxagentsEditField.Value = 20;
            app.MaxspeedEditFieldLabel = uilabel(app.DataforgraphsPanel);
            app.MaxspeedEditFieldLabel.Enable = 'off';
            app.MaxspeedEditFieldLabel.Text = 'Max speed';
            app.MaxspeedEditField = uieditfield(app.DataforgraphsPanel, 'numeric');
            app.MaxspeedEditField.Limits = [1 Inf];
            app.MaxspeedEditField.RoundFractionalValues = 'on';
            app.MaxspeedEditField.Enable = 'off';
            app.MaxspeedEditField.Value = 2;
            app.MinfieldsizeLabel = uilabel(app.DataforgraphsPanel);
            app.MinfieldsizeLabel.Enable = 'off';
            app.MinfieldsizeLabel.Text = 'Min field size';
            app.MinfieldsizeEditField = uieditfield(app.DataforgraphsPanel, 'numeric');
            app.MinfieldsizeEditField.Limits = [1 Inf];
            app.MinfieldsizeEditField.RoundFractionalValues = 'on';
            app.MinfieldsizeEditField.Enable = 'off';
            app.MinfieldsizeEditField.Value = 20;
            app.SetnumberofiterationsSpinnerLabel = uilabel(app.DataforgraphsPanel);
            app.SetnumberofiterationsSpinnerLabel.Text = 'Set number of iterations';
            app.SetnumberofiterationsSpinner = uispinner(app.DataforgraphsPanel);
            app.SetnumberofiterationsSpinner.Limits = [1 Inf];
            app.SetnumberofiterationsSpinner.Value = 1;
            app.YaxisListBoxLabel = uilabel(app.Panel_3);
            app.YaxisListBoxLabel.Text = 'Y axis';
            app.YaxisListBox = uilistbox(app.Panel_3);
            app.YaxisListBox.Items = {'Winnings', 'Defense time'};
            app.YaxisListBox.Multiselect = 'on';
            app.YaxisListBox.ValueChangedFcn = createCallbackFcn(app, @YaxisListBoxValueChanged, true);
            app.YaxisListBox.Value = {};
            app.XaxisListBoxLabel = uilabel(app.Panel_3);
            app.XaxisListBoxLabel.Text = 'X axis';
            app.XaxisListBox = uilistbox(app.Panel_3);
            app.XaxisListBox.Items = {'Number of agents', 'Field size', 'Linear speed', ''};
            app.XaxisListBox.Multiselect = 'on';
            app.XaxisListBox.ValueChangedFcn = createCallbackFcn(app, @XaxisListBoxValueChanged, true);
            app.XaxisListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = simulation_ui2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end